classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TourintothePictureUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        OptionalSelectForegroundPolygonButton  matlab.ui.control.StateButton
        SelectBackgroundRectangleButton  matlab.ui.control.StateButton
        SelectVanishingPointButton      matlab.ui.control.StateButton
        UploadnewImageButton            matlab.ui.control.Button
        TourintothePictureLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        ErrorlogTextArea                matlab.ui.control.TextArea
        ErrorlogTextAreaLabel           matlab.ui.control.Label
        ForegroundSegmentationSwitchLabel  matlab.ui.control.Label
        ForegroundSegmentationSwitch    matlab.ui.control.Switch
        SelecttheforegroundpolygonLampLabel  matlab.ui.control.Label
        SelecttheforegroundpolygonLamp  matlab.ui.control.Lamp
        SelectthevanishingpointLabel_2  matlab.ui.control.Label
        SelectthebackgroundrectangleLamp  matlab.ui.control.Lamp
        SelectthevanishingpointLampLabel  matlab.ui.control.Label
        SelectthevanishingpointLamp     matlab.ui.control.Lamp
        SelectthevanishingpointLabel    matlab.ui.control.Label
        UploadanimageLamp               matlab.ui.control.Lamp
        Start3DTourButton               matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        PleaseuploadapngorjpgfileLabel_5  matlab.ui.control.Label
        StepbyStepLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        VanishingPointROI
        BackgroundROI 
        ForegroundROIList = {}
        SubmitButtonInvoker = Invoker();
        backendReceiver = Receiver();
        img % image that is being shown
        Lines = {};
        UseSeg = "off";
        ImageFileName;
    end
    
    methods (Access = private)
        
        function isInside = checkPointInRectangle(app)
            % isInside: Boolean value indicating if the point is inside the rectangle
            point = app.VanishingPointROI;
            rect = app.BackgroundROI;
            % Get rectangle position (x, y, width, height)
            rectPos = rect.Position;
            rectX = rectPos(1);
            rectY = rectPos(2);
            rectWidth = rectPos(3);
            rectHeight = rectPos(4);
            
            % Get point position (x, y)
            pointPos = point.Position;
            pointX = pointPos(1);
            pointY = pointPos(2);
            
            % Check if the point is within the rectangle bounds
            isInside = pointX >= rectX && pointX <= (rectX + rectWidth) && ...
                       pointY >= rectY && pointY <= (rectY + rectHeight);

            if isInside
                % set the step status to green
                app.SelectthebackgroundrectangleLamp.Color = [0.00,1.00,0.00];
                app.SelectthevanishingpointLamp.Color = [0.00,1.00,0.00];
                % Reset the Error log TextArea
                app.ErrorlogTextArea.Value = '';
            else
                % set the step status to red
                app.SelectthebackgroundrectangleLamp.Color = [1.00,0.00,0.00];
                app.SelectthevanishingpointLamp.Color = [1.00,0.00,0.00];
                app.ErrorlogTextArea.Value = "Error from user input: background rectangle does not include the vanishing point!";
            end

        end

        function plotLines(app)
            % Plot lines from the vanishing point through the corners of the background rectangle

            if isempty(app.VanishingPointROI) || isempty(app.BackgroundROI)
                return;
            end

            isInside = checkPointInRectangle(app); 
            if isInside   
                % Get vanishing point coordinates
                vp_coords = app.VanishingPointROI.Position;
                
                % Get background rectangle corners
                rect = app.BackgroundROI;
                rectPos = rect.Position;
                rectCorners = [
                    rectPos(1), rectPos(2); % Bottom-left
                    rectPos(1) + rectPos(3), rectPos(2); % Bottom-right
                    rectPos(1), rectPos(2) + rectPos(4); % Top-left
                    rectPos(1) + rectPos(3), rectPos(2) + rectPos(4) % Top-right
                ];
    
                % Get image size
                imgWidth = app.UIAxes.XLim(2);
                imgHeight = app.UIAxes.YLim(2);
    
                % Plot lines from vanishing point to each corner and extend to image boundaries
                hold(app.UIAxes, 'on');
                for i = 1:size(rectCorners, 1)
                    % Calculate line equation y = mx + b
                    [m, b] = app.lineEquation(vp_coords, rectCorners(i, :));
    
                    % Determine if the line should go to the upper or lower part of the image
                    if rectCorners(i, 2) < vp_coords(2)
                        direction = 'lower';
                    else
                        direction = 'upper';
                    end
    
                    % Calculate intersection points with image boundaries
                    [xIntersect, yIntersect] = app.calculateIntersections(m, b, vp_coords, imgWidth, imgHeight, direction);
    
                    % Draw the line
                    app.Lines{end+1} = line(app.UIAxes, [vp_coords(1), xIntersect], [vp_coords(2), yIntersect], 'Color', 'r', "LineWidth", 4);
                end
                hold(app.UIAxes, 'off');
            end
        end

        function [m, b] = lineEquation(app, P1, P2)
            if P1(1) == P2(1) % vertical line
                m = Inf;
                b = P1(1);
            else
                m = (P2(2) - P1(2)) / (P2(1) - P1(1)); % slope
                b = P1(2) - m * P1(1); % y-intercept
            end
        end

        function [xIntersect, yIntersect] = calculateIntersections(app, m, b, vp_coords, imgWidth, imgHeight, direction)
            % Calculate intersections with image boundaries

            % Initialize arrays to hold intersection points
            xIntersects = [];
            yIntersects = [];
            
            if isinf(m) % Vertical line
                xIntersect = vp_coords(1);
                if strcmp(direction, 'upper')
                    yIntersect = imgHeight;
                else
                    yIntersect = 1;
                end
                return;
            end

            % Intersect with left (x = 1) boundary
            yLeft = m * 1 + b;
            if yLeft >= 1 && yLeft <= imgHeight
                xIntersects = [xIntersects, 1];
                yIntersects = [yIntersects, yLeft];
            end

            % Intersect with right (x = imgWidth) boundary
            yRight = m * imgWidth + b;
            if yRight >= 1 && yRight <= imgHeight
                xIntersects = [xIntersects, imgWidth];
                yIntersects = [yIntersects, yRight];
            end

            % Intersect with top (y = 1) boundary
            xTop = (1 - b) / m;
            if xTop >= 1 && xTop <= imgWidth
                xIntersects = [xIntersects, xTop];
                yIntersects = [yIntersects, 1];
            end

            % Intersect with bottom (y = imgHeight) boundary
            xBottom = (imgHeight - b) / m;
            if xBottom >= 1 && xBottom <= imgWidth
                xIntersects = [xIntersects, xBottom];
                yIntersects = [yIntersects, imgHeight];
            end

            % Select the intersection point based on direction
            if strcmp(direction, 'upper')
                if ~isempty(yIntersects(yIntersects >= vp_coords(2)))
                    xIntersect = xIntersects(yIntersects >= vp_coords(2));
                    yIntersect = yIntersects(yIntersects >= vp_coords(2));
                else
                    xIntersect = xIntersects(1);
                    yIntersect = yIntersects(1);
                end
            else
                if ~isempty(yIntersects(yIntersects <= vp_coords(2)))
                    xIntersect = xIntersects(yIntersects <= vp_coords(2));
                    yIntersect = yIntersects(yIntersects <= vp_coords(2));
                else
                    xIntersect = xIntersects(1);
                    yIntersect = yIntersects(1);
                end
            end
        end


        function onVanishingPointMoved(app, src, evt)
            % Get the new position of the vanishing point        
            % Perform the necessary actions based on the new position
            if ~isempty(app.Lines)
                for ii=1:length(app.Lines)
                    app.Lines{ii}.Visible = "off";
                end
                app.Lines = {};
            end
            app.plotLines();
            bringToFront(app.VanishingPointROI);
            app.SelectthevanishingpointLamp.Color = [0.00, 1.00, 0.00];
            isInside = checkPointInRectangle(app); 
        end

        function onBackgroundRectangleMoved(app, src, evt)
            % Get the new position of the vanishing point        
            % Perform the necessary actions based on the new position
            if ~isempty(app.Lines)
                for ii=1:length(app.Lines)
                    app.Lines{ii}.Visible = "off";
                end
                app.Lines = {};
            end
            app.plotLines();
            bringToFront(app.BackgroundROI);
            bringToFront(app.VanishingPointROI);
            isInside = checkPointInRectangle(app); 
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadnewImageButton
        function UploadnewImageButtonPushed(app, event)
            % Open file selection dialog box for image files
            if ~isempty(app.img)
                ResetButtonPushed(app, event)
            end
            app.UploadanimageLamp.Color = [1.00,1.00,0.00];

            % Move two directories up
            [twoDirsUpPath, ~, ~] = fileparts(fileparts(fileparts(which(mfilename)))); 
            defaultPath = fullfile(twoDirsUpPath, 'static', 'images');
            [file, path] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an Image', defaultPath);
            if isequal(file, 0)
                app.UploadanimageLamp.Color = [1.00,1.00,0.00];
            else
                % Clear any existing plots in UIAxes
                cla(app.UIAxes);

                % Create the full file path and display the image on UIAxes
                fullFilePath = fullfile(path, file);
                % Load the image
   
                imageData = imread(fullFilePath);
                
                % Get dimensions of the image
                [imageHeight, imageWidth, ~] = size(imageData);
                
                % Get current position of the UIAxes
                axPosition = getpixelposition(app.UIAxes);
                
                % Calculate aspect ratios
                imageAspectRatio = imageWidth / imageHeight;
                axAspectRatio = axPosition(3) / axPosition(4);
                
                % Determine the new dimensions to fit the image within the UIAxes
                if imageAspectRatio > axAspectRatio
                    % Image is wider relative to the available space
                    newWidth = axPosition(3);
                    newHeight = newWidth / imageAspectRatio;
                else
                    % Image is taller relative to the available space
                    newHeight = axPosition(4);
                    newWidth = newHeight * imageAspectRatio;
                end
                % Center the UIAxes within the original space
                newX = axPosition(1) + (axPosition(3) - newWidth) / 2;
                newY = axPosition(2) + (axPosition(4) - newHeight) / 2; 
 
                setpixelposition(app.UIAxes, [newX, newY, newWidth, newHeight]);

                % Display the image in the adjusted UIAxes
                app.img = imshow(fullFilePath, 'Parent', app.UIAxes);
                % Extract the file name from the full file path
                [~, fileName, ~] = fileparts(fullFilePath);
                app.ImageFileName = fileName;
                
                % Set the axes limits to match the image dimensions
                app.UIAxes.XLim = [0, imageWidth];
                app.UIAxes.YLim = [0, imageHeight];
                
                % Maintain the aspect ratio of the image
                app.UIAxes.DataAspectRatio = [1 1 1];

                % set image upload status to Green
                app.UploadanimageLamp.Color = [0.00,1.00,0.00];
                app.SelectVanishingPointButton.Enable = 'on';
                app.SelectBackgroundRectangleButton.Enable = 'on';
                app.OptionalSelectForegroundPolygonButton.Enable = 'on';
                app.Start3DTourButton.Enable = 'on';
                
            end
            figure(app.TourintothePictureUIFigure);
        end

        % Value changed function: SelectVanishingPointButton
        function SelectVanishingPointButtonValueChanged(app, event)
            % only one selection method should be set at a time
            app.SelectBackgroundRectangleButton.Value = 0;
            app.OptionalSelectForegroundPolygonButton.Value = 0;
            
            if ~isempty(app.VanishingPointROI )
                app.VanishingPointROI.Visible = "off";
            end
            
            if ~isempty(app.Lines)
                for ii=1:length(app.Lines)
                    app.Lines{ii}.Visible = "off";
                end
                app.Lines = {};
            end

            app.VanishingPointROI = drawpoint("Color", "r", "Parent", app.UIAxes);

            if ~isempty(app.BackgroundROI)
                app.plotLines();
            else
                % set the step status to in process (yellow)
                app.SelectthevanishingpointLamp.Color = [1.00,1.00,0.00];
            end
            bringToFront(app.VanishingPointROI);
            addlistener(app.VanishingPointROI, 'ROIMoved', @(src, evt) app.onVanishingPointMoved(src, evt));
        end

        % Callback function
        function ImageClicked(app, event)
           % currently unused
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % currently unused
        end

        % Value changed function: SelectBackgroundRectangleButton
        function SelectBackgroundRectangleButtonValueChanged(app, event)
            % only one selection method should be set at a time
            app.SelectVanishingPointButton.Value = 0;
            app.OptionalSelectForegroundPolygonButton.Value = 0;
            
            if ~isempty(app.BackgroundROI)
                app.BackgroundROI.Visible = "off";
            end

            if ~isempty(app.Lines)
                for ii=1:length(app.Lines)
                    app.Lines{ii}.Visible = "off";
                end
                app.Lines = {};
            end

            app.BackgroundROI = drawrectangle('Color','b', 'Parent', ...
                app.UIAxes);
            
            if ~isempty(app.VanishingPointROI)
                app.plotLines();
                bringToFront(app.VanishingPointROI);
            else
                % set the step status to in process (yellow)
                app.SelectthebackgroundrectangleLamp.Color = [1.00,1.00,0.00];
            end
            
            addlistener(app.BackgroundROI, 'ROIMoved', @(src, evt) app.onBackgroundRectangleMoved(src, evt));
        end

        % Button pushed function: Start3DTourButton
        function Start3DTourButtonPushed(app, event)
             % Clear error log
            app.ErrorlogTextArea.Value = '';
            % check input parameters
            if ~isempty(app.BackgroundROI) & ~isempty(app.BackgroundROI) 
                isInside = checkPointInRectangle(app); 
                if isInside

                    command = Create3DModelCommand(app.VanishingPointROI, app.BackgroundROI, app.backendReceiver, app.img, app.ForegroundROIList, app.UseSeg, app.ImageFileName);
                    app.SubmitButtonInvoker.setCommand(command);
                    app.SubmitButtonInvoker.executeCommand();
                end
            else
                app.ErrorlogTextArea.Value = 'Error: vanishing point and background rectangle need to be defined!';
            end
           
            
        end

        % Value changed function: OptionalSelectForegroundPolygonButton
        function OptionalSelectForegroundPolygonButtonValueChanged(app, event)
            app.SelectVanishingPointButton.Value = 0;
            app.SelectBackgroundRectangleButton.Value = 0;
            
            % set the step status to in process (green)
            app.SelecttheforegroundpolygonLamp.Color = [0.00,1.00,0.00];

            newPolygon = drawpolygon('Color','g', 'Parent', app.UIAxes);
            app.ForegroundROIList{end+1} = newPolygon;

            app.OptionalSelectForegroundPolygonButton.Value = 0;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Clear the VanishingPointROI if it exists
            if ~isempty(app.VanishingPointROI)
                app.VanishingPointROI = [];
            end
            
            % Clear the BackgroundROI if it exists
            if ~isempty(app.BackgroundROI)
                app.BackgroundROI = [];
            end
    
            if ~isempty(app.Lines)
                    for ii=1:length(app.Lines)
                        app.Lines{ii}.Visible = "off";
                    end
                    app.Lines = {};
            end
            
            % Clear the ForegroundROI if it exists
            if ~isempty(app.ForegroundROIList)
                app.ForegroundROIList = {};
            end
            
            % Reset all lamps to their default color 
            app.UploadanimageLamp.Color = [1.00,0.00,0.00];
            app.SelectthevanishingpointLamp.Color = [1.00,0.00,0.00];
            app.SelectthebackgroundrectangleLamp.Color = [1.00,0.00,0.00];
            app.SelecttheforegroundpolygonLamp.Color = [0.90,0.90,0.90];
            
            % Clear the image from the UIAxes
            cla(app.UIAxes);
            app.img = [];

            % reset axis position
            app.UIAxes.Position = [3 146 603 491];
            
            % Reset the Error log TextArea
            app.ErrorlogTextArea.Value = '';
            
            % Reset the state of the buttons
            app.SelectVanishingPointButton.Value = false;
            app.SelectBackgroundRectangleButton.Value = false;
            app.OptionalSelectForegroundPolygonButton.Value = false;
            
            app.SelectVanishingPointButton.Enable = 'off';
            app.SelectBackgroundRectangleButton.Enable = 'off';
            app.OptionalSelectForegroundPolygonButton.Enable = 'off';
            app.Start3DTourButton.Enable = 'off';
        
        end

        % Value changed function: ForegroundSegmentationSwitch
        function ForegroundSegmentationSwitchValueChanged(app, event)
            app.UseSeg = app.ForegroundSegmentationSwitch.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TourintothePictureUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {706, 706};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {609, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TourintothePictureUIFigure = uifigure('Visible', 'off');
            app.TourintothePictureUIFigure.Name = 'Tour into the Picture';
            app.TourintothePictureUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TourintothePictureUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Visible = 'off';
            app.TourintothePictureLabel = uilabel(app.LeftPanel);
            app.TourintothePictureLabel.Text = 'Tour into the Picture';
            app.UploadnewImageButton = uibutton(app.LeftPanel, 'push');
            app.UploadnewImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadnewImageButtonPushed, true);
            app.UploadnewImageButton.Tooltip = {'Supported file formats: .jpg and .png'};
            app.UploadnewImageButton.Text = 'Upload new Image';
            app.SelectVanishingPointButton = uibutton(app.LeftPanel, 'state');
            app.SelectVanishingPointButton.ValueChangedFcn = createCallbackFcn(app, @SelectVanishingPointButtonValueChanged, true);
            app.SelectVanishingPointButton.Enable = 'off';
            app.SelectVanishingPointButton.Tooltip = {'Click inside the image to add the vanishing point. Drag the point to reposition it.'};
            app.SelectVanishingPointButton.Text = 'Select Vanishing Point';
            app.SelectBackgroundRectangleButton = uibutton(app.LeftPanel, 'state');
            app.SelectBackgroundRectangleButton.ValueChangedFcn = createCallbackFcn(app, @SelectBackgroundRectangleButtonValueChanged, true);
            app.SelectBackgroundRectangleButton.Enable = 'off';
            app.SelectBackgroundRectangleButton.Tooltip = {'Click and hold inside the image to define the background rectangle. Drag the sides or corners to adjust its size. Adjust the rectangle so that the vanishing lines (red) align with the room edges.'};
            app.SelectBackgroundRectangleButton.Text = 'Select Background Rectangle';
            app.OptionalSelectForegroundPolygonButton = uibutton(app.LeftPanel, 'state');
            app.OptionalSelectForegroundPolygonButton.ValueChangedFcn = createCallbackFcn(app, @OptionalSelectForegroundPolygonButtonValueChanged, true);
            app.OptionalSelectForegroundPolygonButton.Enable = 'off';
            app.OptionalSelectForegroundPolygonButton.Tooltip = {'Click on the image to define the vertices of your polygon. Each click will add a new vertex. To close the polygon, simply click on the first vertex again. You can adjust the positions of the vertices by dragging them. Multiple foreground objects are supported, and you can create multiple polygons as needed.'};
            app.OptionalSelectForegroundPolygonButton.Text = 'Optional: Select Foreground Polygon';
            app.RightPanel = uipanel(app.GridLayout);
            app.StepbyStepLabel = uilabel(app.RightPanel);
            app.StepbyStepLabel.Text = 'Step by Step';
            app.PleaseuploadapngorjpgfileLabel_5 = uilabel(app.RightPanel);
            app.PleaseuploadapngorjpgfileLabel_5.Text = 'Hint: This option is recommended only for rough selection of the object by the user.';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Start3DTourButton = uibutton(app.RightPanel, 'push');
            app.Start3DTourButton.ButtonPushedFcn = createCallbackFcn(app, @Start3DTourButtonPushed, true);
            app.Start3DTourButton.Enable = 'off';
            app.Start3DTourButton.Text = 'Start 3D Tour';
            app.UploadanimageLamp = uilamp(app.RightPanel);
            app.SelectthevanishingpointLabel = uilabel(app.RightPanel);
            app.SelectthevanishingpointLabel.Tooltip = {''};
            app.SelectthevanishingpointLabel.Text = '1) Upload an image';
            app.SelectthevanishingpointLamp = uilamp(app.RightPanel);
            app.SelectthevanishingpointLampLabel = uilabel(app.RightPanel);
            app.SelectthevanishingpointLampLabel.Tooltip = {''};
            app.SelectthevanishingpointLampLabel.Text = '2) Select the vanishing point';
            app.SelectthebackgroundrectangleLamp = uilamp(app.RightPanel);
            app.SelectthevanishingpointLabel_2 = uilabel(app.RightPanel);
            app.SelectthevanishingpointLabel_2.Tooltip = {''};
            app.SelectthevanishingpointLabel_2.Text = '3) Select the background rectangle';
            app.SelecttheforegroundpolygonLamp = uilamp(app.RightPanel);
            app.SelecttheforegroundpolygonLamp.Tooltip = {''};
            app.SelecttheforegroundpolygonLampLabel = uilabel(app.RightPanel);
            app.SelecttheforegroundpolygonLampLabel.Tooltip = {'Click on the image to define the vertices of your polygon. Each click will add a new vertex. To close the polygon, simply click on the first vertex again. Multiple foreground objects are supported.'};
            app.SelecttheforegroundpolygonLampLabel.Text = '4) Select the foreground polygon';
            app.ForegroundSegmentationSwitch = uiswitch(app.RightPanel, 'slider');
            app.ForegroundSegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @ForegroundSegmentationSwitchValueChanged, true);
            app.ForegroundSegmentationSwitchLabel = uilabel(app.RightPanel);
            app.ForegroundSegmentationSwitchLabel.Text = 'Foreground Segmentation';
            app.ErrorlogTextAreaLabel = uilabel(app.RightPanel);
            app.ErrorlogTextAreaLabel.Text = 'Error log';
            app.ErrorlogTextArea = uitextarea(app.RightPanel);
            app.ErrorlogTextArea.Editable = 'off';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'TUM_Logo_blau_rgb_p_transparent.png';
            % Show the figure after all components are created
            app.TourintothePictureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TourintothePictureUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TourintothePictureUIFigure)
        end
    end
end