classdef tip_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        OptionalSelectForegroundRectangleButton  matlab.ui.control.StateButton
        SelectBackgroundRectangleButton  matlab.ui.control.StateButton
        TourintothePictureLabel         matlab.ui.control.Label
        SelectVanishingPointButton      matlab.ui.control.StateButton
        UploadnewImageButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        SelecttheforegroundrectangleLamp  matlab.ui.control.Lamp
        SelecttheforegroundrectangleLampLabel  matlab.ui.control.Label
        ErrorlogTextArea                matlab.ui.control.TextArea
        ErrorlogTextAreaLabel           matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        UploadanimageLamp               matlab.ui.control.Lamp
        SelectthevanishingpointLabel    matlab.ui.control.Label
        SelectthebackgroundrectangleLamp  matlab.ui.control.Lamp
        SelectthevanishingpointLabel_2  matlab.ui.control.Label
        SelectthevanishingpointLamp     matlab.ui.control.Lamp
        SelectthevanishingpointLampLabel  matlab.ui.control.Label
        SUBMITButton                    matlab.ui.control.Button
        StepbyStepLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        VanishingPointROI
        BackgroundROI % 
        ForegroundROI
        SubmitButtonInvoker = Invoker();
        backendReceiver = Receiver();
        img % image that is being shown
    end
    
    methods (Access = private)
        
        function isInside = checkPointInRectangle(app)
            % rect: A drawrectangle object
            % point: A drawpoint object
            % isInside: Boolean value indicating if the point is inside the rectangle
            point = app.VanishingPointROI;
            rect = app.BackgroundROI;
            % Get rectangle position (x, y, width, height)
            rectPos = rect.Position;
            rectX = rectPos(1);
            rectY = rectPos(2);
            rectWidth = rectPos(3);
            rectHeight = rectPos(4);
            
            % Get point position (x, y)
            pointPos = point.Position;
            pointX = pointPos(1);
            pointY = pointPos(2);
            
            % Check if the point is within the rectangle bounds
            isInside = pointX >= rectX && pointX <= (rectX + rectWidth) && ...
                       pointY >= rectY && pointY <= (rectY + rectHeight);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadnewImageButton
        function UploadnewImageButtonPushed(app, event)
            % Open file selection dialog box for image files
            app.UploadanimageLamp.Color = [1.00,1.00,0.00];
            [file, path] = uigetfile({'*.jpg;*.png', 'Image Files (*.jpg, *.png)'}, 'Select an Image');
            if isequal(file, 0)
                disp('User selected Cancel');
                app.UploadanimageLamp.Color = [1.00,1.00,0.00];
            else
                % Create the full file path and display the image on UIAxes
                fullFilePath = fullfile(path, file);
                % Clear any existing plots in UIAxes
                cla(app.UIAxes);
                % Display image in UIAxes

                app.img = imshow(fullFilePath, 'Parent', app.UIAxes);
                disp(['User selected ', fullFilePath]);
                % set image upload status to Green
                app.UploadanimageLamp.Color = [0.00,1.00,0.00];
                app.SelectVanishingPointButton.Enable = 'on';
                app.SelectBackgroundRectangleButton.Enable = 'on';
                app.OptionalSelectForegroundRectangleButton.Enable = 'on';
            end
        end

        % Value changed function: SelectVanishingPointButton
        function SelectVanishingPointButtonValueChanged(app, event)
            % only one selection method should be set at a time
            app.SelectBackgroundRectangleButton.Value = 0;
            app.OptionalSelectForegroundRectangleButton.Value = 0;
            
            if ~isempty(app.VanishingPointROI )
                app.VanishingPointROI.Visible = "off";
            end

            app.VanishingPointROI = drawpoint("Color", "r", "Parent", app.UIAxes);

            % check if background was set 
            if isempty(app.BackgroundROI) 
                % set the step status to in process (yellow)
                app.SelectthevanishingpointLamp.Color = [1.00,1.00,0.00];
                
            else
                % check if vanishing point was set properly 
                isInside = checkPointInRectangle(app); 
                if isInside
                    % set the step status to green
                    app.SelectthevanishingpointLamp.Color = [0.00,1.00,0.00];
                    app.SelectthebackgroundrectangleLamp.Color = [0.00,1.00,0.00];
                else
                    % set the step status to red
                    app.SelectthevanishingpointLamp.Color = [1.00,0.00,0.00];
                    app.ErrorlogTextArea.Value = "Error selecting vanishing point: selection is not within " + ...
                        "the background rectangle!";
                end
            end
               
        end

        % Callback function
        function ImageClicked(app, event)
           disp(event)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % currently unused
        end

        % Value changed function: SelectBackgroundRectangleButton
        function SelectBackgroundRectangleButtonValueChanged(app, event)
            % only one selection method should be set at a time
            app.SelectVanishingPointButton.Value = 0;
            app.OptionalSelectForegroundRectangleButton.Value = 0;
            
            if ~isempty(app.BackgroundROI )
                app.BackgroundROI.Visible = "off";
            end
            app.BackgroundROI = drawrectangle('Color','b', 'Parent', ...
                app.UIAxes);
            % check if VP was set 
            if isempty(app.VanishingPointROI) 
                % set the step status to in process (yellow)
                app.SelectthebackgroundrectangleLamp.Color = [1.00,1.00,0.00];
                
            else
                % check if vanishing point was set properly 
                isInside = checkPointInRectangle(app); 
                if isInside
                    % set the step status to green
                    app.SelectthebackgroundrectangleLamp.Color = [0.00,1.00,0.00];
                    app.SelectthevanishingpointLamp.Color = [0.00,1.00,0.00];
                else
                    % set the step status to red
                    app.SelectthebackgroundrectangleLamp.Color = [1.00,0.00,0.00];
                    app.ErrorlogTextArea.Value = "Error selecting background rectangle: selection does not include the vanishing point!";
                end
            end
        end

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            % todo: implement command
            command = Create3DModelCommand(app.VanishingPointROI, app.BackgroundROI, app.backendReceiver, app.img);
            app.SubmitButtonInvoker.setCommand(command);
            app.SubmitButtonInvoker.executeCommand();
            
        end

        % Value changed function: OptionalSelectForegroundRectangleButton
        function OptionalSelectForegroundRectangleButtonValueChanged(app, event)
             app.SelectVanishingPointButton.Value = 0;
            app.SelectBackgroundRectangleButton.Value = 0;
            
            if ~isempty(app.ForegroundROI )
                app.ForegroundROI.Visible = "off";
            end
            % set the step status to in process (green)
            app.SelecttheforegroundrectangleLamp.Color = [0.00,1.00,0.00];
            app.ForegroundROI = drawrectangle('Color','g', 'Parent', ...
                app.UIAxes);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        % Clear the VanishingPointROI if it exists
        if ~isempty(app.VanishingPointROI)
            delete(app.VanishingPointROI);
            app.VanishingPointROI = [];
        end
        
        % Clear the BackgroundROI if it exists
        if ~isempty(app.BackgroundROI)
            delete(app.BackgroundROI);
            app.BackgroundROI = [];
        end
        
        % Clear the ForegroundROI if it exists
        if ~isempty(app.ForegroundROI)
            delete(app.ForegroundROI);
            app.ForegroundROI = [];
        end
        
        % Reset all lamps to their default color 
        app.UploadanimageLamp.Color = [1.00,0.00,0.00];
        app.SelectthevanishingpointLamp.Color = [1.00,0.00,0.00];
        app.SelectthebackgroundrectangleLamp.Color = [1.00,0.00,0.00];
        app.SelecttheforegroundrectangleLamp.Color = [0.90,0.90,0.90];
        
        % Clear the image from the UIAxes
        cla(app.UIAxes);
        app.img = [];
        
        % Reset the Error log TextArea
        app.ErrorlogTextArea.Value = '';
        
        % Reset the state of the buttons
        app.SelectVanishingPointButton.Value = false;
        app.SelectBackgroundRectangleButton.Value = false;
        app.OptionalSelectForegroundRectangleButton.Value = false;
        
        app.SelectVanishingPointButton.Enable = 'off';
        app.SelectBackgroundRectangleButton.Enable = 'off';
        app.OptionalSelectForegroundRectangleButton.Enable = 'off';
        app.SUBMITButton.Enable = 'off';
        
        % Add any additional reset logic specific to your app here
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {706, 706};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {609, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Visible = 'off';
            app.UploadnewImageButton = uibutton(app.LeftPanel, 'push');
            app.UploadnewImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadnewImageButtonPushed, true);
            app.UploadnewImageButton.Text = 'Upload new Image';
            app.SelectVanishingPointButton = uibutton(app.LeftPanel, 'state');
            app.SelectVanishingPointButton.ValueChangedFcn = createCallbackFcn(app, @SelectVanishingPointButtonValueChanged, true);
            app.SelectVanishingPointButton.Enable = 'off';
            app.SelectVanishingPointButton.Text = 'Select Vanishing Point';
            app.TourintothePictureLabel = uilabel(app.LeftPanel);
            app.TourintothePictureLabel.Text = 'Tour into the Picture';
            app.SelectBackgroundRectangleButton = uibutton(app.LeftPanel, 'state');
            app.SelectBackgroundRectangleButton.ValueChangedFcn = createCallbackFcn(app, @SelectBackgroundRectangleButtonValueChanged, true);
            app.SelectBackgroundRectangleButton.Enable = 'off';
            app.SelectBackgroundRectangleButton.Text = 'Select Background Rectangle';
            app.OptionalSelectForegroundRectangleButton = uibutton(app.LeftPanel, 'state');
            app.OptionalSelectForegroundRectangleButton.ValueChangedFcn = createCallbackFcn(app, @OptionalSelectForegroundRectangleButtonValueChanged, true);
            app.OptionalSelectForegroundRectangleButton.Enable = 'off';
            app.OptionalSelectForegroundRectangleButton.Text = 'Optional: Select Foreground Rectangle';
            app.RightPanel = uipanel(app.GridLayout);
            app.StepbyStepLabel = uilabel(app.RightPanel);
            app.StepbyStepLabel.Text = 'Step by Step';
            app.SUBMITButton = uibutton(app.RightPanel, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.SelectthevanishingpointLampLabel = uilabel(app.RightPanel);
            app.SelectthevanishingpointLampLabel.Text = '2) Select the vanishing point';
            app.SelectthevanishingpointLamp = uilamp(app.RightPanel);
            app.SelectthevanishingpointLabel_2 = uilabel(app.RightPanel);
            app.SelectthevanishingpointLabel_2.Text = '3) Select the background rectangle';
            app.SelectthebackgroundrectangleLamp = uilamp(app.RightPanel);
            app.SelectthevanishingpointLabel = uilabel(app.RightPanel);
            app.SelectthevanishingpointLabel.Text = '1) Upload an image';
            app.UploadanimageLamp = uilamp(app.RightPanel);
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ErrorlogTextAreaLabel = uilabel(app.RightPanel);
            app.ErrorlogTextAreaLabel.Text = 'Error log';
            app.ErrorlogTextArea = uitextarea(app.RightPanel);
            app.SelecttheforegroundrectangleLampLabel = uilabel(app.RightPanel);
            app.SelecttheforegroundrectangleLampLabel.Text = '4) Select the foreground rectangle';
            app.SelecttheforegroundrectangleLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tip_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end